classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Panel2                matlab.ui.container.Panel
        Panel2Grid            matlab.ui.container.GridLayout
        FontColor             matlab.ui.control.DropDown
        FontColorLabel        matlab.ui.control.Label
        FontWeight            matlab.ui.control.DropDown
        FontWeightLabel       matlab.ui.control.Label
        FontSize              matlab.ui.control.DropDown
        FontSizeLabel         matlab.ui.control.Label
        FontName              matlab.ui.control.DropDown
        FontNameLabel         matlab.ui.control.Label
        BackgroundColor       matlab.ui.control.DropDown
        BackgroundColorLabel  matlab.ui.control.Label
        Grid2                 matlab.ui.container.GridLayout
        Slider                matlab.ui.control.Slider
        CheckBox              matlab.ui.control.CheckBox
        Label3                matlab.ui.control.Label
        Panel1                matlab.ui.container.Panel
        Panel1Grid            matlab.ui.container.GridLayout
        ccButton5             ccTools.Button
        ccButton4             ccTools.Button
        ccButton3             ccTools.Button
        ccButton2             ccTools.Button
        ccButton1             ccTools.Button
        Label2                matlab.ui.control.Label
        UITable               matlab.ui.control.Table
        ccTable               ccTools.Table
        Grid1                 matlab.ui.container.GridLayout
        OpenFile              ccTools.Button
        Label1                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ccButton1, ccButton2, ccButton3, ccButton4, 
        % ...and 1 other component
        function ButtonPushed(app, event)
            
            switch event.Source
                case app.ccButton1
                    ccTools.MessageBox(app.UIFigure, '<font style="font-size: 16px;">ccTools</font><br>MessageBox', 'winBackgroundColor', '#a2142f', 'msgFontColor', 'white')

                case app.ccButton2
                    d = ccTools.ProgressDialog(app.UIFigure, 'color', '#a2142f');
                    pause(3)
                    delete(d)

                case app.ccButton3
                    d = ccTools.ProgressDialog(app.UITable);
                    pause(3)
                    delete(d)

                case app.ccButton4
                    if strcmp(app.ccButton4.BorderColor, '#808080')
                        set(app.ccButton4, 'BorderColor', '#a2142f', 'tFontColor', '#a2142f')
                    else
                        set(app.ccButton4, 'BorderColor', '#808080', 'tFontColor', 'black')
                    end

                case app.ccButton5
                    if strcmp(app.ccButton5.tFontColor, 'white')
                        set(app.ccButton5, 'BackgroundColor', [0.80,0.80,0.80], 'tFontColor', 'black', 'BorderRadius', '25%', 'BorderWidth', 2, 'BorderColor', 'black')
                    else
                        set(app.ccButton5, 'BackgroundColor', [0.32,0.33,0.36], 'tFontColor', 'white', 'BorderRadius', '50%', 'BorderWidth', 0)
                    end
            end

        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            
            if app.CheckBox.Value
                set(app.Panel2Grid.Children, 'Enable', 1)
                UITableHeaderCustomization(app)
            else
                set(app.Panel2Grid.Children, 'Enable', 0)
            end

        end

        % Value changed function: BackgroundColor, FontColor, FontName, 
        % ...and 2 other components
        function UITableHeaderCustomization(app, event)

            ccTools.compCustomization(app.UITable, 'backgroundHeaderColor', app.BackgroundColor.Value, ...
                                                   'fontFamily',            app.FontName.Value, ...
                                                   'fontSize',              app.FontSize.Value, ...
                                                   'fontWeight',            app.FontWeight.Value, ...
                                                   'color',                 app.FontColor.Value);

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
            ccTools.compCustomization(app.UITable, 'borderRadius', sprintf('%.0fpx', event.Value));
            
        end

        % Callback function: OpenFile
        function OpenFilePushed(app, event)
            
            [fName, fPath] = uigetfile('*.xlsx');
            figure(app.UIFigure)
            
            try
                tempTable = readtable(fullfile(fPath, fName));
                app.ccTable.Data = tempTable;
                app.UITable.Data = tempTable;
                
            catch ME
                ccTools.MessageBox(app.UIFigure, getReport(ME), 'winWidth', '440px')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Grid1 = uigridlayout(app.GridLayout);
            app.Label1 = uilabel(app.Grid1);
            app.Label1.Text = 'ccTools.Table';
            app.OpenFile = ccTools.Button(app.Grid1);
            app.OpenFile.Model = 'Icon';
            app.OpenFile.HorizontalAlign = 'center';
            app.OpenFile.BorderRadius = '0px';
            app.OpenFile.BorderPadding = 1;
            app.OpenFile.ButtonPushedFcn = createCallbackFcn(app, @OpenFilePushed, true);
            app.ccTable = ccTools.Table(app.GridLayout);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Label2 = uilabel(app.GridLayout);
            app.Label2.Text = 'ccTools.Button';
            app.Panel1 = uipanel(app.GridLayout);
            app.Panel1Grid = uigridlayout(app.Panel1);
            app.ccButton1 = ccTools.Button(app.Panel1Grid);
            app.ccButton1.Text = 'ccTools.MessageBox';
            app.ccButton1.Description = 'Open modal element';
            app.ccButton1.HorizontalAlign = 'left';
            app.ccButton1.tFontSize = 14;
            app.ccButton1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ccButton2 = ccTools.Button(app.Panel1Grid);
            app.ccButton2.Text = 'ccTools.ProgressDialog';
            app.ccButton2.Description = 'Open modal element above uifigure';
            app.ccButton2.HorizontalAlign = 'center';
            app.ccButton2.tFontSize = 14;
            app.ccButton2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ccButton3 = ccTools.Button(app.Panel1Grid);
            app.ccButton3.Text = 'Progress dialog';
            app.ccButton3.Description = 'Open modal element above uitable';
            app.ccButton3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ccButton4 = ccTools.Button(app.Panel1Grid);
            app.ccButton4.Text = 'Btn';
            app.ccButton4.Description = '';
            app.ccButton4.HorizontalAlign = 'center';
            app.ccButton4.BorderRadius = '20px';
            app.ccButton4.BorderPadding = 0;
            app.ccButton4.IconWidth = 24;
            app.ccButton4.IconHeight = 24;
            app.ccButton4.tFontSize = 16;
            app.ccButton4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ccButton5 = ccTools.Button(app.Panel1Grid);
            app.ccButton5.Model = 'Text';
            app.ccButton5.Text = 'Btn';
            app.ccButton5.Description = '';
            app.ccButton5.HorizontalAlign = 'center';
            app.ccButton5.BorderRadius = '50%';
            app.ccButton5.tFontSize = 28;
            app.ccButton5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label3 = uilabel(app.GridLayout);
            app.Label3.Text = 'ccTools.compCustomization';
            app.Grid2 = uigridlayout(app.GridLayout);
            app.CheckBox = uicheckbox(app.Grid2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'Header customization';
            app.Slider = uislider(app.Grid2);
            app.Slider.Limits = [0 20];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Panel2 = uipanel(app.GridLayout);
            app.Panel2Grid = uigridlayout(app.Panel2);
            app.BackgroundColorLabel = uilabel(app.Panel2Grid);
            app.BackgroundColorLabel.Enable = 'off';
            app.BackgroundColorLabel.Text = 'BackgroundColor';
            app.BackgroundColor = uidropdown(app.Panel2Grid);
            app.BackgroundColor.Items = {'#52555C', '#808080', '#F0F0F0', '#009578', '#CE644A', '#A2142F'};
            app.BackgroundColor.ValueChangedFcn = createCallbackFcn(app, @UITableHeaderCustomization, true);
            app.BackgroundColor.Enable = 'off';
            app.BackgroundColor.Value = '#009578';
            app.FontNameLabel = uilabel(app.Panel2Grid);
            app.FontNameLabel.Enable = 'off';
            app.FontNameLabel.Text = 'FontName';
            app.FontName = uidropdown(app.Panel2Grid);
            app.FontName.Items = {'Courier', 'Helvetica', 'Symbol', 'Times New Roman'};
            app.FontName.ValueChangedFcn = createCallbackFcn(app, @UITableHeaderCustomization, true);
            app.FontName.Enable = 'off';
            app.FontName.Value = 'Times New Roman';
            app.FontSizeLabel = uilabel(app.Panel2Grid);
            app.FontSizeLabel.Enable = 'off';
            app.FontSizeLabel.Text = 'FontSize';
            app.FontSize = uidropdown(app.Panel2Grid);
            app.FontSize.Items = {'8px', '10px', '12px', '14px'};
            app.FontSize.ValueChangedFcn = createCallbackFcn(app, @UITableHeaderCustomization, true);
            app.FontSize.Enable = 'off';
            app.FontSize.Value = '12px';
            app.FontWeightLabel = uilabel(app.Panel2Grid);
            app.FontWeightLabel.Enable = 'off';
            app.FontWeightLabel.Text = 'FontWeight';
            app.FontWeight = uidropdown(app.Panel2Grid);
            app.FontWeight.Items = {'normal', 'bold'};
            app.FontWeight.ValueChangedFcn = createCallbackFcn(app, @UITableHeaderCustomization, true);
            app.FontWeight.Enable = 'off';
            app.FontWeight.Value = 'bold';
            app.FontColorLabel = uilabel(app.Panel2Grid);
            app.FontColorLabel.Enable = 'off';
            app.FontColorLabel.Text = 'FontColor';
            app.FontColor = uidropdown(app.Panel2Grid);
            app.FontColor.Items = {'white', 'black'};
            app.FontColor.ValueChangedFcn = createCallbackFcn(app, @UITableHeaderCustomization, true);
            app.FontColor.Enable = 'off';
            app.FontColor.Value = 'white';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end